classdef Parallel_robot_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ComputeButton                matlab.ui.control.Button
        xpEditFieldLabel             matlab.ui.control.Label
        xpEditField                  matlab.ui.control.NumericEditField
        ypEditFieldLabel             matlab.ui.control.Label
        ypEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        InputLabel                   matlab.ui.control.Label
        q1EditFieldLabel             matlab.ui.control.Label
        q1EditField                  matlab.ui.control.NumericEditField
        q2EditFieldLabel             matlab.ui.control.Label
        q2EditField                  matlab.ui.control.NumericEditField
        OutputLabel                  matlab.ui.control.Label
        ExitButton                   matlab.ui.control.Button
        Compute2DparallelrobotLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.UIFigure)
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.q1EditField.Value = sqrt(app.xpEditField.Value^2 + app.ypEditField.Value^2)
            app.q2EditField.Value = sqrt((app.aEditField.Value - app.xpEditField.Value)^2 + app.ypEditField.Value^2)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.xpEditFieldLabel = uilabel(app.UIFigure);
            app.xpEditFieldLabel.Text = 'xp';
            app.xpEditField = uieditfield(app.UIFigure, 'numeric');
            app.ypEditFieldLabel = uilabel(app.UIFigure);
            app.ypEditFieldLabel.Text = 'yp';
            app.ypEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Compute2DparallelrobotLabel = uilabel(app.UIFigure);
            app.Compute2DparallelrobotLabel.Text = 'Compute 2D parallel robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parallel_robot_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end